classdef CustomParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        ToolTab              matlab.ui.container.Tab
        WorkpieceTab         matlab.ui.container.Tab
        ProcessTab           matlab.ui.container.Tab
        CoolingLubricantTab  matlab.ui.container.Tab
        RectificationTab     matlab.ui.container.Tab
        Label8               matlab.ui.control.Label
        Label7               matlab.ui.control.Label
        Label6               matlab.ui.control.Label
        Label5               matlab.ui.control.Label
        Label4               matlab.ui.control.Label
        Label3               matlab.ui.control.Label
        Label2               matlab.ui.control.Label
        Label1               matlab.ui.control.Label
        Field8               matlab.ui.control.NumericEditField
        Field7               matlab.ui.control.NumericEditField
        Field6               matlab.ui.control.NumericEditField
        Field5               matlab.ui.control.NumericEditField
        Field4               matlab.ui.control.NumericEditField
        Field3               matlab.ui.control.NumericEditField
        Field2               matlab.ui.control.NumericEditField
        SaveAllButton        matlab.ui.control.Button
        Field1               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        PrimaryApp;
        numParam;
        parameter;

        tool_var = ["Diameter (mm)", "Mean Grain Height (um)", "Std. Dev. Grain Height","Hardness", "Porosity"];
        work_var = ["Hardness", "Length", "Width", "Height"];
        proc_var = ["Grinding Wheel RPM", "Feed Rate", "Depth of Cut", "Passes Per Layer"];
        cool_var = ["If Cooling", "Cooling Rate"];
        rec_var = ["If Dressing" "Cut Depth"];

        new_tool;
        new_work;
        new_cool;
        new_proc;
        new_rec;

    end
    
    methods (Access = private)
        function closing(app)
            %Updates the current 
            app.getVals();

            app.PrimaryApp.toolChange(app.new_tool);
            app.PrimaryApp.coolChange(app.new_cool);
            app.PrimaryApp.procChange(app.new_proc);
            app.PrimaryApp.workChange(app.new_work);
            app.PrimaryApp.recChange(app.new_rec);
        end
        
        function makeVis(app)
            
            app.Field1.Visible = "off";
            app.Field2.Visible = "off";
            app.Field3.Visible = "off";
            app.Field4.Visible = "off";
            app.Field5.Visible = "off";
            app.Field6.Visible = "off";
            app.Field7.Visible = "off";
            app.Field8.Visible = "off";

            app.Label1.Visible = "off";
            app.Label2.Visible = "off";
            app.Label3.Visible = "off";
            app.Label4.Visible = "off";
            app.Label5.Visible = "off";
            app.Label6.Visible = "off";
            app.Label7.Visible = "off";
            app.Label8.Visible = "off";

            for i = 1:app.numParam
                switch i
                    case 1
                        app.Field1.Visible = "on";
                        app.Label1.Visible = "on";
                    case 2
                        app.Field2.Visible = "on";
                        app.Label2.Visible = "on";
                    case 3
                        app.Field3.Visible = "on";
                        app.Label3.Visible = "on";
                    case 4
                        app.Field4.Visible = "on";
                        app.Label4.Visible = "on";
                    case 5
                        app.Field5.Visible = "on";
                        app.Label5.Visible = "on";
                    case 6
                        app.Field6.Visible = "on";
                        app.Label6.Visible = "on";
                    case 7
                        app.Field7.Visible = "on";
                        app.Label7.Visible = "on";
                    case 8
                        app.Field8.Visible = "on";
                        app.Label8.Visible = "on";
                end
            end
        end

        function assignDef(app)
            switch app.parameter
                case "Tool"
                    switch_val = app.new_tool;
                    vars = app.tool_var;
                case "Cooling Lubricant"
                    switch_val = app.new_cool;
                    vars = app.cool_var;
                case "Process"
                    switch_val = app.new_proc;
                    vars = app.proc_var;
                case "Workpiece"
                    switch_val = app.new_work;
                    vars = app.work_var;
                case "Rectification"
                    switch_val = app.new_rec;
                    vars = app.rec_var;
            end

            for i = 1:length(switch_val)
                switch i 
                    case 1
                        app.Field1.Value = switch_val{1};
                        app.Label1.Text = vars(1);
                    case 2
                        app.Field2.Value = switch_val{2};
                        app.Label2.Text = vars(2);
                    case 3
                        app.Field3.Value = switch_val{3};
                        app.Label3.Text = vars(3);
                    case 4
                        app.Field4.Value = switch_val{4};
                        app.Label4.Text = vars(4);
                    case 5
                        app.Field5.Value = switch_val{5};
                        app.Label5.Text = vars(5);
                    case 6
                        app.Field6.Value = switch_val{6};
                        app.Label6.Text = vars(6);
                    case 7
                        app.Field7.Value = switch_val{7};
                        app.Label7.Text = vars(7);
                    case 8
                        app.Field8.Value = switch_val{8};
                        app.Label8.Text = vars(8);
                end
            end
        end

        function getVals(app)
            vals = cell(1, app.numParam);
            for i = 1:app.numParam
                switch i
                    case 1
                        vals{i} = app.Field1.Value;
                    case 2
                        vals{i} = app.Field2.Value;
                    case 3
                        vals{i} = app.Field3.Value;
                    case 4
                        vals{i} = app.Field4.Value;
                    case 5
                        vals{i} = app.Field5.Value;
                    case 6
                        vals{i}  = app.Field6.Value;
                    case 7
                        vals{i}  = app.Field7.Value;
                    case 8
                        vals{i}  = app.Field8.Value;
                end
            end

            %Grabs old tab's data
            switch app.parameter
                case "Tool"
                    app.new_tool = vals;
                case "Cooling Lubricant"
                    app.new_cool = vals;
                case "Process"
                    app.new_proc = vals;
                case "Workpiece"
                    app.new_work = vals;
                case "Rectification"
                    app.new_rec = vals;
            end

        end
              
        function updateTab(app)
            %Grabs old tab's data
            if app.parameter ~= "start"
                app.getVals();
            end

            %Updates info for new tab
            selectedTab = app.TabGroup.SelectedTab.Title;

            switch selectedTab
                case "Tool"
                    app.numParam = length(app.tool_var);
                    app.parameter = "Tool";
                case "Cooling Lubricant"
                    app.numParam = length(app.cool_var);
                    app.parameter = "Cooling Lubricant";
                case "Process"
                    app.numParam = length(app.proc_var);
                    app.parameter = "Process";
                case "Workpiece"
                    app.numParam = length(app.work_var);
                    app.parameter = "Workpiece";
                case "Rectification"
                    app.numParam = length(app.rec_var);
                    app.parameter = "Rectification";
            end

            app.assignDef();
            app.makeVis();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.PrimaryApp = callingApp;

            app.new_tool = app.PrimaryApp.getTool();
            app.new_work = app.PrimaryApp.getWork();
            app.new_cool = app.PrimaryApp.getCool();
            app.new_proc = app.PrimaryApp.getProc();
            app.new_rec = app.PrimaryApp.getRec();

            app.parameter = "start";
            app.updateTab();
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            app.closing();
            app.delete;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.closing();
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateTab();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Field1 = uieditfield(app.UIFigure, 'numeric');
            app.Field1.Visible = 'off';
            app.SaveAllButton = uibutton(app.UIFigure, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'Save All';
            app.Field2 = uieditfield(app.UIFigure, 'numeric');
            app.Field2.Visible = 'off';
            app.Field3 = uieditfield(app.UIFigure, 'numeric');
            app.Field3.Visible = 'off';
            app.Field4 = uieditfield(app.UIFigure, 'numeric');
            app.Field4.Visible = 'off';
            app.Field5 = uieditfield(app.UIFigure, 'numeric');
            app.Field5.Visible = 'off';
            app.Field6 = uieditfield(app.UIFigure, 'numeric');
            app.Field6.Visible = 'off';
            app.Field7 = uieditfield(app.UIFigure, 'numeric');
            app.Field7.Visible = 'off';
            app.Field8 = uieditfield(app.UIFigure, 'numeric');
            app.Field8.Visible = 'off';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Visible = 'off';
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Visible = 'off';
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Visible = 'off';
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Visible = 'off';
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Visible = 'off';
            app.Label6.Text = 'Label6';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Visible = 'off';
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Visible = 'off';
            app.Label8.Text = 'Label8';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ToolTab = uitab(app.TabGroup);
            app.ToolTab.Title = 'Tool';
            app.WorkpieceTab = uitab(app.TabGroup);
            app.WorkpieceTab.Title = 'Workpiece';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.CoolingLubricantTab = uitab(app.TabGroup);
            app.CoolingLubricantTab.Title = 'Cooling Lubricant';
            app.RectificationTab = uitab(app.TabGroup);
            app.RectificationTab.Title = 'Rectification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomParam(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end