classdef GrindingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Slider                         matlab.ui.control.Slider
        TaskCompletionGauge            matlab.ui.control.LinearGauge
        ButtonGroup                    matlab.ui.container.ButtonGroup
        StartGrindingProcessButton     matlab.ui.control.ToggleButton
        InitiateWorkpieceButton        matlab.ui.control.ToggleButton
        GenerateGrindingWheelButton    matlab.ui.control.ToggleButton
        EditParametersButton           matlab.ui.control.Button
        RectificationDropDown          matlab.ui.control.DropDown
        RectificationDropDownLabel     matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        SaveDataButton                 matlab.ui.control.Button
        ToolDropDown                   matlab.ui.control.DropDown
        ToolDropDownLabel              matlab.ui.control.Label
        ProcessDropDown                matlab.ui.control.DropDown
        ProcessDropDownLabel           matlab.ui.control.Label
        VisualizationLabel             matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        CoolingLubricantDropDown       matlab.ui.control.DropDown
        CoolingLubricantDropDownLabel  matlab.ui.control.Label
        GrindingParametersLabel        matlab.ui.control.Label
        WorkpieceDropDown              matlab.ui.control.DropDown
        WorkpieceDropDownLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Second Screen
        CustomParam;
        
        %Parameter Option
        tool_opt = ["Default", "Dremel"];
        cool_opt = ["None"];
        proc_opt = ["Default"];
        work_opt = ["Default"];
        rec_opt = ["None"];

        
        %Parameter Values
        tool_val 
        cool_val 
        proc_val 
        work_val 
        rec_val

        %Variables of interest
        tool_var = ["Diameter (mm)", "Mean Grain Height (um)", "Std. Dev. Grain Height","Hardness", "Porosity"];
        work_var = ["Hardness", "Length", "Width", "Height"];
        proc_var = ["Grinding Wheel RPM", "Feed Rate", "Depth of Cut", "Passes Per Layer"];
        cool_var = ["If Cooling", "Cooling Rate"];
        rec_var = ["If Dressing" "Cut Depth"];

        %GW Surface
        gw_surf

        %Process Cuts for each grain
        proc_cuts

        %WP surface
        wp_surf

    end
    
    methods (Access = public)
        
        %Setters
        function defaultChange(app)
            app.tool_val = {1 2 3 4 5};
            app.cool_val = {1 2};
            app.proc_val = {1 2 3 4};
            app.work_val = {1 2 3 4};
            app.rec_val = {1 2};
        end
        
        function toolChange(app, newToolVal)
            app.tool_val = newToolVal;
        end

        function coolChange(app, newCoolVal)
            app.cool_val = newCoolVal;
        end

        function procChange(app, newProcVal)
            app.proc_val = newProcVal;
        end

        function workChange(app, newWorkVal)
            app.work_val = newWorkVal;
        end

        function recChange(app, newRecVal)
            app.rec_val = newRecVal;
        end

        %Getters
        function toolVal = getTool(app)
            toolVal = app.tool_val;
        end

        function coolVal = getCool(app)
            coolVal = app.cool_val;
        end

        function procVal = getProc(app)
            procVal = app.proc_val;
        end

        function workVal = getWork(app)
            workVal = app.work_val;
        end

        function recVal = getRec(app)
            recVal = app.rec_val;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ToolDropDown.Items = app.tool_opt;
            app.CoolingLubricantDropDown.Items = app.cool_opt;
            app.ProcessDropDown.Items = app.proc_opt;
            app.WorkpieceDropDown.Items = app.work_opt;
            app.RectificationDropDown.Items = app.rec_opt;
            app.Slider.Visible = 'off';

            app.defaultChange();

            app.UITable.Data = [0 0 0 0 0 0 0 0]';

            app.UITable.RowName = {"Var1" "Var2" "Var3" "Var4" "Var5" "Var6" "Var7" "Var8"};

        end

        % Value changed function: ToolDropDown
        function ToolDropDownValueChanged(app, event)
            value = app.ToolDropDown.Value;
            if value == "Default"
                app.defaultChange();
            elseif value == "Dremel"
                
            end
        end

        % Value changed function: ProcessDropDown
        function ProcessDropDownValueChanged(app, event)
            value = app.ProcessDropDown.Value;
            if value == "Default"
                app.defaultChange();
            end
        end

        % Value changed function: WorkpieceDropDown
        function WorkpieceDropDownValueChanged(app, event)
            value = app.WorkpieceDropDown.Value;
            if value == "Default"
                app.defaultChange();
            end
        end

        % Value changed function: CoolingLubricantDropDown
        function CoolingLubricantDropDownValueChanged(app, event)
            value = app.CoolingLubricantDropDown.Value;
            if value == "None"
                app.defaultChange();
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.SaveDataButton.Visible = "off";
            app.RunButton.Visible = "off";
            
            %Searches for empty sheet
            empty = false;
            sheetNum = 1;

            while empty == false
                sheetName = "Sheet" + num2str(sheetNum);
                try
                    T = readtable("Grinding Data.xls", 'Sheet',sheetName);
                    if isempty(T)
                        break
                    end
                catch
                    break
                end
                sheetNum = sheetNum + 1;
            end
            
            writetable(table(app.UITable.Data, 'VariableNames', {'Value'},'RowNames', app.UITable.RowName), "Grinding Data.xls", 'WriteRowNames',true, 'Sheet', sheetName);

            visName = "Visual"+num2str(sheetNum) + ".jpg";
            exportgraphics(app.UIAxes, visName);

            disp(app.tool_val)

            pause(app.pause_time);

            app.SaveDataButton.Visible = "on";
            app.RunButton.Visible = "on";
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.SaveDataButton.Visible = "off";
            app.RunButton.Visible = "off";
            app.TaskCompletionGauge.Value = 0;
            
            pause(.0001)

            %Mean grain height (mm)
            u_g = 0.102;

            %Std Dev grain height
            o_g = abs((.038-u_g)/3.5);
            
            %Scale of the graph
            scale_gw = u_g/5;
                       
            %Diameter of the grinding wheel (mm)
            d_gw = 12.7;

            %Grinding wheel radius
            r_gw = d_gw/2;

            %Grinding wheel width (mm)
            w_gw = 12.7; 

            %Workpiece length height and width (mm)
            wp_x = 15;
            wp_y = 15;
            wp_z = 1;

            %Grinding Wheel RPM
            rpm_gw = 2000;

            %Feed Rate in mm/sec
            feed_rate = 500;

            if app.ButtonGroup.SelectedObject.Text == "Generate Grinding Wheel"
                       
                %All units are in mm
    
                %Calculate the Grinding Wheel Geometry
                                   
                %Grinding wheel circumference (mm)
                c_gw = pi*d_gw;
                
                %Generates the x y z for the grinding wheel surface
                x_gw = 0:scale_gw:w_gw;
                y_gw = 0:scale_gw:c_gw;
                z_gw(1:length(x_gw), 1:length(y_gw)) = 0;
    
                %Calculate the grain density using equation 6.34 (grain/mm^2)
                %Currently will use a place holder value to test code
                density_a = 1/(2.25*(2*u_g)^2);
    
                %Number of Grains 
                n_g = round(density_a*c_gw*w_gw);
                
                %Number of placed grains
                n_p = 0;
                
                %Vector containg the grain x coords (scaled)
                x_g = zeros(1, n_g);
                
                %Vector containg the grain y coords (scaled)
                y_g = zeros(1, n_g);
    
                %Vector containing grain heights
                g_h = o_g.*randn(1, n_g) +u_g;
                tries = 0;
    
                while n_p < n_g && tries < 100000
                    %Get a random coordinate on the grinding wheel surface
                    x_try = randi([1, length(x_gw)]);
                    y_try = randi([1, length(y_gw)]);
                    
                    %Set valid to true as default
                    valid = true;
    
                    %Calculate the distance this point and all points
                    if n_p > 0
                        
                        for i = 1:n_p+1
                            %Calculates the distance in mm
                            dist = sqrt((x_try-x_g(i))^2+(y_try-y_g(i))^2)*scale_gw;
                            
                            %For checks if the grains are too close, overlap,
                            %dist < 1/(density_a) ||
                            if  dist < g_h(i) + g_h(n_p+1)
                                valid = false;
                                break;
                            end
    
                        end
                    end
    
                    %Checks if the grain is over the edge
                    if x_try + round(g_h(n_p+1)/scale_gw) > length(x_gw) || x_try - round(g_h(n_p+1)/scale_gw) < 1 || y_try + round(g_h(n_p+1)/scale_gw) > length(y_gw) || y_try - round(g_h(n_p+1)/scale_gw) < 1
                        valid = false;
                    end
    
                    if valid
                        x_g(n_p+1) = x_try;
                        y_g(n_p+1) = y_try;
                        n_p = n_p +1;
                    end
                    
                    tries = tries +1;
                end
                
                %Sets the grain center to the grain height if valid point
    
                %Calculate the x bounds for the hemisphere for each y value
    
                for i = 1:length(g_h)
                    if x_g(i) ~= 0 && y_g(i) ~=0
                        g_h_scaled = round(g_h(i)/scale_gw);
                        grid_center = g_h_scaled+1;
        
                        [grid_rows, grid_cols] = meshgrid(1:(g_h_scaled*2+1), 1:(g_h_scaled*2+1));
        
                        circle_points = (grid_rows-grid_center).^2+(grid_cols-grid_center).^2 <= g_h_scaled.^2;
                        sphere_heights = sqrt(g_h_scaled.^2-((grid_rows-grid_center).^2+(grid_cols-grid_center).^2));
                        
                        sphere_heights(circle_points == 0) = 0;
        
                        z_gw(x_g(i)-g_h_scaled:x_g(i)+g_h_scaled, y_g(i)-g_h_scaled:y_g(i)+g_h_scaled) = sphere_heights;
                    end                    
                end
    
                app.gw_surf = z_gw;
                surf(app.UIAxes, app.gw_surf, 'LineStyle','none');
               
                %End of grinding wheel surface generation ends here

            elseif app.ButtonGroup.SelectedObject.Text == "Initiate Workpiece"
    
                x_wp = 0:scale_gw:wp_x;
                y_wp = 0:scale_gw:wp_y;
    
                %Workpiece surface (scaled)
                z_wp(1:length(x_wp), 1:length(y_wp)) = wp_z/scale_gw;
                app.wp_surf = z_wp;
                      
                %Rotations per second
                rps_gw = rpm_gw/60;
        
                %Radial Velocity
                rad_gw = rps_gw*2*pi;
                                   
                %Final cut depth in mm
                cut_depth = 0.1;

                %GW size
                [x_gw, y_gw] = size(app.gw_surf);
    
                %Calculate the GW center location
                z_center = wp_z+r_gw-cut_depth;
                                                  
                %Calculates the max grain height
                max_grain = max(app.gw_surf(:));
                    
                %Calculate the x area on the wp affected per rotation

                %Calculates the angle from gw center to wp
                theta = asin((z_center-wp_z)/(r_gw+max_grain*scale_gw));
                rot_length = 2*cos(theta)*(r_gw+max_grain*scale_gw);
                feed_length = (pi-2*theta)/rad_gw*feed_rate;

                tot_length = round((rot_length+feed_length)/scale_gw);
                                
                %Creates a 3D array for each layer of the grinding wheel
                all_cuts = zeros(x_gw, tot_length, y_gw);
                
                %Calculate the removed area from each layer for one rot
                for i = 1:y_gw                  
                                        
                    %Creates an empty 2D array for the z values
                    z_cut = zeros(x_gw, tot_length);

                    %Gets the radii
                    radii = app.gw_surf(:, i);

                    %Calculates the surface for the y_length
                    for j = 1:tot_length

                        z_cut_vals = ones(length(radii), 1)*wp_z/scale_gw;

                        for k = 1:length(radii)

                            if radii(k) ~= 0
                                eqn = @(t) -(r_gw/scale_gw+radii(k))*cos(rad_gw*t+theta)+feed_rate*t/scale_gw-j+1+round(rot_length/2/scale_gw);
                                der = @(t) (r_gw/scale_gw+radii(k))*rad_gw*sin(rad_gw*t+theta)+feed_rate/scale_gw;
                                z_cut_vals(k) = -(radii(k)+r_gw/scale_gw)*sin(theta+rad_gw*newton(eqn, der, 0, .1));
                            end

                        end

                        z_cut(:, j) = z_cut_vals;

                    end
                    
                    %Displays cross sections
                    % for o = 1:length(z_cut(:, 1))
                    %     if sum(z_cut(o, :)==wp_z/scale_gw) < 1
                    %         plot(1:length(z_cut(o, :)), z_cut(o, :))
                    %         break
                    %     end
                    % end
                    
                    % surf(app.UIAxes, z_cut, 'LineStyle','none');                                                           
                    app.TaskCompletionGauge.Value = (i/y_gw*100);
                    pause(.00000000001)

                    all_cuts(:, :, i) = z_cut;
                                        
                end

                app.Slider.Limits = [1, y_gw];
                app.Slider.Visible = "on";

                surf(app.UIAxes, all_cuts(:,:, app.Slider.Value), 'LineStyle','none');
                app.proc_cuts = all_cuts+z_center/scale_gw;
                %End of process initiation

            else

                %Calculate layers per feed unit
                t = 1/(feed_rate);
                v_gw = rpm_gw*60*2*pi*r_gw;
                num_lay = t*v_gw/scale_gw;

                %GW size
                [x_gw, y_gw] = size(app.gw_surf);

                %WP size
                [x_wp, y_wp] = size(app.wp_surf);

                %Total layers
                tot_lay = y_gw;

                %Sets  layers per feed to the number of gw layers if over                
                if num_lay > tot_lay
                    num_lay = tot_lay;
                end

                %Start positions
                x_start = round(-w_gw/2/scale_gw);
                % y_start = round(-r_gw/scale_gw);
                y_start = -30;

                %Calculates the vertical positions
                vert_change = x_start:x_gw:(x_wp);
                horz_change = y_start:y_wp;

                %The current layer
                cur_layer = 1;

                new_wp_surf = app.wp_surf;
                all_cuts = app.proc_cuts;

                comp_proc = 0;
                total_proc = length(vert_change)*length(horz_change)*num_lay;
                
                %Vertical motion
                for i = vert_change

                    %Horizontal motion
                    for j = horz_change

                        %Goes through layers per cut
                        for k = cur_layer:(num_lay+cur_layer)
                            tic

                            cur_layer = mod(k-1, tot_lay)+1;
                            cut_layer = all_cuts(:, :, cur_layer);
                            if sum(new_wp_surf(:) >  min(cut_layer(:))) > 1
                                new_wp_surf = remove(new_wp_surf, cut_layer, i, j);
                                surf(app.UIAxes, new_wp_surf, 'LineStyle','none');
                            end

                            comp_proc = comp_proc + 1;
                            app.TaskCompletionGauge.Value = (comp_proc/total_proc)*100;
                            pause(0.0000001);
                            toc

                        end

                    end

                end

                app.wp_surf = new_wp_surf;
             
            end
                      
            %Do analysis of the surface
            app.UITable.Data = randi([0 100], 8, 1);
                      
            
            app.SaveDataButton.Visible = "on";
            app.RunButton.Visible = "on";
        end

        % Value changed function: RectificationDropDown
        function RectificationDropDownValueChanged(app, event)
            value = app.RectificationDropDown.Value;
            if value == "None"
                app.defaultChange();
            end
        end

        % Button pushed function: EditParametersButton
        function EditParametersButtonPushed(app, event)
            app.CustomParam = CustomParam(app);
        end

        % Callback function
        function SwitchValueChanged(app, event)

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            app.Slider.Visible = "off";
            app.Slider.Value = 1;
            
            if selectedButton == "Generate Grinding Wheel"
                app.RunButton.Visible = "on";
                surf(app.UIAxes, app.gw_surf, 'LineStyle','none');

            elseif selectedButton == "Initiate Workpiece"
                
                if (isempty(app.gw_surf))
                    app.RunButton.Visible = "off";
                else
                    app.RunButton.Visible = "on";
                end

                if ~(isempty(app.proc_cuts) || isempty(app.gw_surf))
                    app.Slider.Visible = "on";
                end

                surf(app.UIAxes, app.proc_cuts(:,:, app.Slider.Value), 'LineStyle','none');

            else
                
                if (isempty(app.proc_cuts) || isempty(app.gw_surf))
                    app.RunButton.Visible = "off";
                else
                    app.RunButton.Visible = "on";
                end

                surf(app.UIAxes, app.wp_surf, 'LineStyle','none');

            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            surf(app.UIAxes, app.proc_cuts(:,:, round(app.Slider.Value)), 'LineStyle','none');        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WorkpieceDropDownLabel = uilabel(app.UIFigure);
            app.WorkpieceDropDownLabel.Text = 'Workpiece';
            app.WorkpieceDropDown = uidropdown(app.UIFigure);
            app.WorkpieceDropDown.ValueChangedFcn = createCallbackFcn(app, @WorkpieceDropDownValueChanged, true);
            app.GrindingParametersLabel = uilabel(app.UIFigure);
            app.GrindingParametersLabel.Text = 'Grinding Parameters';
            app.CoolingLubricantDropDownLabel = uilabel(app.UIFigure);
            app.CoolingLubricantDropDownLabel.Text = 'Cooling Lubricant';
            app.CoolingLubricantDropDown = uidropdown(app.UIFigure);
            app.CoolingLubricantDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolingLubricantDropDownValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.VisualizationLabel = uilabel(app.UIFigure);
            app.VisualizationLabel.Text = 'Visualization';
            app.ProcessDropDownLabel = uilabel(app.UIFigure);
            app.ProcessDropDownLabel.Text = 'Process';
            app.ProcessDropDown = uidropdown(app.UIFigure);
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownValueChanged, true);
            app.ToolDropDownLabel = uilabel(app.UIFigure);
            app.ToolDropDownLabel.Text = 'Tool';
            app.ToolDropDown = uidropdown(app.UIFigure);
            app.ToolDropDown.ValueChangedFcn = createCallbackFcn(app, @ToolDropDownValueChanged, true);
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Value'};
            app.UITable.RowName = {'Var1, Var2'};
            app.RectificationDropDownLabel = uilabel(app.UIFigure);
            app.RectificationDropDownLabel.Text = 'Rectification';
            app.RectificationDropDown = uidropdown(app.UIFigure);
            app.RectificationDropDown.ValueChangedFcn = createCallbackFcn(app, @RectificationDropDownValueChanged, true);
            app.EditParametersButton = uibutton(app.UIFigure, 'push');
            app.EditParametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditParametersButtonPushed, true);
            app.EditParametersButton.Text = 'Edit Parameters';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GenerateGrindingWheelButton = uitogglebutton(app.ButtonGroup);
            app.GenerateGrindingWheelButton.Text = 'Generate Grinding Wheel';
            app.GenerateGrindingWheelButton.Value = true;
            app.InitiateWorkpieceButton = uitogglebutton(app.ButtonGroup);
            app.InitiateWorkpieceButton.Text = 'Initiate Workpiece';
            app.StartGrindingProcessButton = uitogglebutton(app.ButtonGroup);
            app.StartGrindingProcessButton.Text = 'Start Grinding Process';
            app.TaskCompletionGauge = uigauge(app.UIFigure, 'linear');
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrindingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end